classdef vehicle_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFRVehicleModelUIFigure         matlab.ui.Figure
        MainMenuPanel                   matlab.ui.container.Panel
        SidebarPanel                    matlab.ui.container.Panel
        SimulatePanel                   matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        SimulationTypeButtonGroup       matlab.ui.container.ButtonGroup
        LaptimeButton                   matlab.ui.control.RadioButton
        ManeuverButton                  matlab.ui.control.RadioButton
        TrackOrManeuverSelectionPanel   matlab.ui.container.Panel
        TrackorManeueverDropDown        matlab.ui.control.DropDown
        EditButton                      matlab.ui.control.Button
        CreateButton_2                  matlab.ui.control.Button
        BrowseButton_2                  matlab.ui.control.Button
        VehicleSelectionPanel           matlab.ui.container.Panel
        VehicleDropDown                 matlab.ui.control.DropDown
        ParametersButton                matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        TitlePanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RyersonFormulaRacingLabel       matlab.ui.control.Label
        VehicleModelLabel               matlab.ui.control.Label
        SimulationPanel                 matlab.ui.container.Panel
        RightSidePanel                  matlab.ui.container.Panel
        ManageSimulationPanel           matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        RHSLeftPanel                    matlab.ui.container.Panel
        GearLabel                       matlab.ui.control.Label
        GearTextLabel                   matlab.ui.control.Label
        ThrottleBrakePanel              matlab.ui.container.Panel
        BrakeGaugeLabel                 matlab.ui.control.Label
        BrakeGauge                      matlab.ui.control.LinearGauge
        ThrottleGauge_2Label            matlab.ui.control.Label
        ThrottleGauge                   matlab.ui.control.LinearGauge
        RHSRPanel                       matlab.ui.container.Panel
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.Gauge
        RPMGaugeLabel                   matlab.ui.control.Label
        RPMGauge                        matlab.ui.control.Gauge
        x1000Label                      matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        CarParametersPanel              matlab.ui.container.Panel
        ParametersPanel                 matlab.ui.container.Panel
        SuspensionPanel                 matlab.ui.container.Panel
        ChangeSuspensionGeometryButton  matlab.ui.control.Button
        SpringRatesPanel                matlab.ui.container.Panel
        FrontLabel                      matlab.ui.control.Label
        FrontSpringRateEditField        matlab.ui.control.NumericEditField
        RearLabel                       matlab.ui.control.Label
        RearSpringRateEditField         matlab.ui.control.NumericEditField
        SpringRateUnitsDropDown         matlab.ui.control.DropDown
        DamperRatesPanel                matlab.ui.container.Panel
        FrontLabel_2                    matlab.ui.control.Label
        FrontDamperRateEditField        matlab.ui.control.NumericEditField
        RearLabel_2                     matlab.ui.control.Label
        RearDamperRateEditField         matlab.ui.control.NumericEditField
        DamperRateUnitsDropDown         matlab.ui.control.DropDown
        SteeringPanel                   matlab.ui.container.Panel
        ChangeSteeringGeometryButton    matlab.ui.control.Button
        ToeAnglesPanel                  matlab.ui.container.Panel
        FrontLabel_3                    matlab.ui.control.Label
        FrontToeEditField               matlab.ui.control.NumericEditField
        RearLabel_3                     matlab.ui.control.Label
        RearToeEditField                matlab.ui.control.NumericEditField
        FrontToeUnitsDrownDown          matlab.ui.control.DropDown
        RearToeUnitsDropDown            matlab.ui.control.DropDown
        CasterUnitsDropDown             matlab.ui.control.DropDown
        CasteranglesLabel               matlab.ui.control.Label
        CasterEditField                 matlab.ui.control.NumericEditField
        GeneralPanel                    matlab.ui.container.Panel
        CarMassUnitsDropDown            matlab.ui.control.DropDown
        TiresLabel                      matlab.ui.control.Label
        TiresDropDown                   matlab.ui.control.DropDown
        CGPositionPanel                 matlab.ui.container.Panel
        HeightLabel                     matlab.ui.control.Label
        CGHeightEditField               matlab.ui.control.NumericEditField
        CGHeightUnitsDropDown           matlab.ui.control.DropDown
        CGLengthUnitsDropDown           matlab.ui.control.DropDown
        LengthEditFieldLabel            matlab.ui.control.Label
        CGLengthEditField               matlab.ui.control.NumericEditField
        WheelbaseLabel                  matlab.ui.control.Label
        WheelbaseEditField              matlab.ui.control.NumericEditField
        WheelbaseUnitsDropDown          matlab.ui.control.DropDown
        YawinertiaLabel                 matlab.ui.control.Label
        YawInertiaEditField             matlab.ui.control.NumericEditField
        YawInertiaUnitsDropDown         matlab.ui.control.DropDown
        TrackWidthPanel                 matlab.ui.container.Panel
        FrontEditField_5Label           matlab.ui.control.Label
        FrontTrackWidthEditField        matlab.ui.control.NumericEditField
        RearEditField_5Label            matlab.ui.control.Label
        RearTrackWidthEditField         matlab.ui.control.NumericEditField
        TrackWidthUnitsDropDown         matlab.ui.control.DropDown
        CarmassEditFieldLabel           matlab.ui.control.Label
        CarMassEditField                matlab.ui.control.NumericEditField
        AerodynamicsPanel               matlab.ui.container.Panel
        AeromassLabel                   matlab.ui.control.Label
        AeroMassEditField               matlab.ui.control.NumericEditField
        AeroMassUnitsDropDown           matlab.ui.control.DropDown
        DragcoefficientEditFieldLabel   matlab.ui.control.Label
        DragCoefficientEditField        matlab.ui.control.NumericEditField
        LiftcoefficientEditFieldLabel   matlab.ui.control.Label
        LiftCoefficientEditField        matlab.ui.control.NumericEditField
        FrontalareaLabel                matlab.ui.control.Label
        FrontalAreaEditField            matlab.ui.control.NumericEditField
        FrontalAreaUnitsDropDown        matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        PowertrainPanel                 matlab.ui.container.Panel
        PowertrainTypeButtonGroup       matlab.ui.container.ButtonGroup
        InternalcombustionButton        matlab.ui.control.RadioButton
        ElectricButton                  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        State       SimulationState     % Simulation state (running, paused, stopped)
        Model       double              % Model reference
        DrivetrainModel double          % Drivetrain model reference
        SpeedListenerHandle
        RPMListenerHandle
        ThrottleListenerHandle
        BrakeListenerHandle
        GearListenerHandle
        ClockListenerHandle
        OldModelProperties struct = struct( ...
            'StopTime', 0, ...
            'StartFcn', '', ...
            'StopFcn', '' ...
            )
        DictParams struct = struct(...
            'carMass', Simulink.Parameter.empty(1, 0), ...
            'cgHeight', Simulink.Parameter.empty(1, 0), ...
            'cgLength', Simulink.Parameter.empty(1, 0), ...
            'wheelbase', Simulink.Parameter.empty(1, 0), ...
            'yawInertia', Simulink.Parameter.empty(1, 0), ...
            'trackWidth', Simulink.Parameter.empty(1, 0), ...
            'springRates', Simulink.Parameter.empty(1, 0), ...
            'damperRates', Simulink.Parameter.empty(1, 0) ...
            )
        Dict Simulink.data.Dictionary
        DictEntries struct = struct(...
            'carMass', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'cgHeight', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'cgLength', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'wheelbase', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'yawInertia', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'trackWidth', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'springRates', Simulink.data.dictionary.Entry.empty(1, 0), ...
            'damperRates', Simulink.data.dictionary.Entry.empty(1, 0) ...
            )
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function changeSimulationState(app, newState)
            oldState = app.State;
            app.State = newState;
            
            switch newState
                
                case SimulationState.Running % Run the sim
                    
                    app.PauseButton.Text = 'Pause';
                    
                    if oldState == SimulationState.Stopped
                        set_param(app.Model,'SimulationCommand','start'); % Start the model
                    else
                        set_param(app.Model,'SimulationCommand','continue'); % Resume the model
                    end
                    
                case SimulationState.Paused % Pause the sim
                    
                    app.PauseButton.Text = 'Resume';
                    
                    set_param(app.Model,'SimulationCommand','pause');
                    
                case SimulationState.Stopped % Stop the sim
                    
                    % Disable the buttons
                    app.PauseButton.Enable = false;
                    app.StopButton.Enable = false;
                    
                    % Stop the simulation
                    set_param(app.Model,'SimulationCommand','stop');
                    
                    % Reset to the old properties
                    set_param(app.Model, 'StopTime', app.OldModelProperties.StopTime);
                    set_param(app.Model, 'StartFcn', app.OldModelProperties.StartFcn);
                    set_param(app.Model, 'StopFcn', app.OldModelProperties.StopFcn);
                    
                    % Reset the gauges
                    app.SpeedGauge.Value = 0;
                    app.RPMGauge.Value = 0;
                    app.ThrottleGauge.Value = 0;
                    app.BrakeGauge.Value = 0;
            end
        end
        
        function changeAppState(app, newState)
            switch newState
                case AppState.CarParameters
                    app.CarParametersPanel.Visible = true;
                    app.SimulationPanel.Visible = false;
                    app.MainMenuPanel.Visible = false;
                    
                    app.Dict = Simulink.data.dictionary.open('Properties.sldd');
                    dataSection = getSection(app.Dict, 'Design Data');
                    
                    % General
                    app.DictEntries.carMass = dataSection.getEntry('Car_mass');
                    app.DictParams.carMass = app.DictEntries.carMass.getValue;
                    app.CarMassEditField.Value = app.DictParams.carMass.Value;
                    app.CarMassUnitsDropDown.Value = app.DictParams.carMass.Unit;
                    
                    app.TiresDropDown.Enable = false;
                    
                    app.DictEntries.cgHeight = dataSection.getEntry('CG_z');
                    app.DictParams.cgHeight = app.DictEntries.cgHeight.getValue;
                    app.CGHeightEditField.Value = app.DictParams.cgHeight.Value;
                    app.CGHeightEditField.Enable = false;
                    app.CGHeightUnitsDropDown.Value = app.DictParams.cgHeight.Unit;
                    
                    app.DictEntries.cgLength = dataSection.getEntry('CG_x');
                    app.DictParams.cgLength = app.DictEntries.cgLength.getValue;
                    app.CGLengthEditField.Value = app.DictParams.cgLength.Value;
                    app.CGLengthUnitsDropDown.Value = app.DictParams.cgLength.Unit;
                    
                    app.DictEntries.wheelbase = dataSection.getEntry('Wheelbase');
                    app.DictParams.wheelbase = app.DictEntries.wheelbase.getValue;
                    app.WheelbaseEditField.Value = app.DictParams.wheelbase.Value;
                    app.WheelbaseUnitsDropDown.Value = app.DictParams.wheelbase.Unit;
                    
                    app.DictEntries.yawInertia = dataSection.getEntry('Car_Iz');
                    app.DictParams.yawInertia = app.DictEntries.yawInertia.getValue;
                    app.YawInertiaEditField.Value = app.DictParams.yawInertia.Value;
                    app.YawInertiaUnitsDropDown.Value = app.DictParams.yawInertia.Unit;
                    
                    app.DictEntries.trackWidth = dataSection.getEntry('Track_width');
                    app.DictParams.trackWidth = getValue(app.DictEntries.trackWidth);
                    trackWidth = app.DictParams.trackWidth.Value;
                    app.FrontTrackWidthEditField.Value = trackWidth(1);
                    app.TrackWidthUnitsDropDown.Value = app.DictParams.trackWidth.Unit;
                    
                    app.RearTrackWidthEditField.Value = trackWidth(1);
                    app.RearTrackWidthEditField.Enable = false;
                    
                    % Suspension
                    app.ChangeSuspensionGeometryButton.Enable = false;
                    
                    app.DictEntries.springRates = dataSection.getEntry('Shock_stiffness');
                    app.DictParams.springRates = app.DictEntries.springRates.getValue;
                    springRates = app.DictParams.springRates.Value;
                    app.FrontSpringRateEditField.Value = springRates(1);
                    app.RearSpringRateEditField.Value = springRates(2);
                    app.SpringRateUnitsDropDown.Value = app.DictParams.springRates.Unit;
                    
                    app.DictEntries.damperRates = dataSection.getEntry('Shock_damping');
                    app.DictParams.damperRates = app.DictEntries.damperRates.getValue;
                    damperRates = app.DictParams.damperRates.Value;
                    app.FrontDamperRateEditField.Value = damperRates(1);
                    app.RearDamperRateEditField.Value = damperRates(2);
                    app.DamperRateUnitsDropDown.Value = app.DictParams.damperRates.Unit;
                    
                    % Steering
                    app.ChangeSteeringGeometryButton.Enable = false;
                    app.FrontToeEditField.Enable = false;
                    app.FrontToeUnitsDrownDown.Enable = false;
                    app.RearToeEditField.Enable = false;
                    app.RearToeUnitsDropDown.Enable = false;
                    app.CasterEditField.Enable = false;
                    app.CasterUnitsDropDown.Enable = false;
                    
                    % Aero
                    app.AeroMassEditField.Enable = false;
                    app.AeroMassUnitsDropDown.Enable = false;
                    app.DragCoefficientEditField.Enable = false;
                    app.LiftCoefficientEditField.Enable = false;
                    app.FrontalAreaEditField.Enable = false;
                    app.FrontalAreaUnitsDropDown.Enable = false;
                    
                case AppState.Simulation
                    app.SimulationPanel.Visible = true;
                    app.MainMenuPanel.Visible = false;
                    app.CarParametersPanel.Visible = false;
                    
                case AppState.Start
                    app.SimulationPanel.Visible = false;
                    app.MainMenuPanel.Visible = true;
                    app.CarParametersPanel.Visible = false;
            end
        end
        
        function registerListeners(app)
            app.SpeedListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model_noLT/Car velocities/Integrator1', ...
                'PostOutputs', ...
                @(block, event) updateSpeed(app, block));
            app.RPMListenerHandle = add_exec_event_listener( ...
                'Drivetrain_2Track/Gain1', ...
                'PostOutputs', ...
                @(block, event) updateRPM(app, block));
            app.ThrottleListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model_noLT/Throttle Input', ...
                'PostOutputs', ...
                @(block, event) updateThrottle(app, block));
            app.BrakeListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model_noLT/Brake pedal', ...
                'PostOutputs', ...
                @(block, event) updateBrake(app, block));
            app.GearListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model_noLT/Gear Input', ...
                'PostOutputs', ...
                @(block, event) updateGear(app, block));
            app.ClockListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model_noLT/Clock', ...
                'PostOutputs', ...
                @(block, event) updateClock(app, block));
        end
        
        function stopSimulation(app)
            app.changeSimulationState(SimulationState.Stopped);
        end
        
        function updateSpeed(app, block)
            value = block.OutputPort(1).Data;
            app.SpeedGauge.Value = value(1) * 3.6;
        end
        
        function updateRPM(app, block)
            value = block.OutputPort(1).Data;
            app.RPMGauge.Value = value(1) / 1000;
        end
        
        function updateThrottle(app, block)
            value = block.OutputPort(1).Data;
            app.ThrottleGauge.Value = value(1) * 100;
        end
        
        function updateBrake(app, block)
            value = block.OutputPort(1).Data;
            app.BrakeGauge.Value = value(1) * 100;
        end
        
        function updateGear(app, block)
            value = block.OutputPort(1).Data;
            app.GearLabel.Text = num2str(value);
        end
        
        function updateClock(app, block)
            value = block.OutputPort(1).Data;
            app.TimeLabel.Text = num2str(value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainMenuPanel.Position = app.RFRVehicleModelUIFigure.Position;
            app.SimulationPanel.Position = app.RFRVehicleModelUIFigure.Position;
            
            changeAppState(app, AppState.Start);
            
            global app_global;
            app_global = app;
        end

        % Button pushed function: PauseButton
        function pauseButtonPushed(app, event)
            switch app.State
                case SimulationState.Running
                    changeSimulationState(app, SimulationState.Paused);
                case SimulationState.Paused
                    changeSimulationState(app, SimulationState.Running);
            end
        end

        % Button pushed function: SimulateButton
        function simulateButtonPushed(app, event)
            app.MainMenuPanel.Visible = false;
            app.SimulationPanel.Visible = true;
            
            app.Model = load_system('Test_Model.slx'); % Load the model
            app.DrivetrainModel = load_system('Drivetrain_2Track.slx'); % Load the drivetrain
            
            % Save the old properties
            app.OldModelProperties.StopTime = get_param(app.Model,'StopTime');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StartFcn');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StopFcn');
            
            set_param(app.Model,'StopTime','inf');
            set_param(app.Model,'StartFcn','global app_global; app_global.registerListeners');
            set_param(app.Model,'StopFcn','global app_global; app_global.stopSimulation');
            
            get_param(app.Model,'SimulationStatus')
            
            changeSimulationState(app, SimulationState.Running);
            
        end

        % Size changed function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureSizeChanged(app, event)
            position = app.RFRVehicleModelUIFigure.Position;
            app.MainMenuPanel.Position = position;
            app.MainMenuPanel.Position(2) = position(2) - 41;
            %app.MainMenuPanel.Position(4) = position(4) + 41;
            
            app.SimulationPanel.Position = position;
            app.SimulationPanel.Position(2) = position(2) - 41;
            app.SimulationPanel.Position(4) = position(4) + 41;
            
            app.SidebarPanel.Position(1) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            app.SidebarPanel.Position(2) = app.MainMenuPanel.Position(2);
            app.SidebarPanel.Position(4) = app.MainMenuPanel.Position(4) + 1;
            
            app.TitlePanel.Position(2) = app.MainMenuPanel.Position(4) - app.TitlePanel.Position(4);
            app.TitlePanel.Position(3) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            
            app.RightSidePanel.Position(1) = app.SimulationPanel.Position(3) - app.RightSidePanel.Position(3);
            app.RightSidePanel.Position(2) = app.SimulationPanel.Position(2);
            app.RightSidePanel.Position(4) = app.SimulationPanel.Position(4);
            
            app.TimeLabel.Position(1) = app.RightSidePanel.Position(1) - 100;
            app.TimeLabel.Position(2) = app.SimulationPanel.Position(4) - 80;
            
            app.CarParametersPanel.Position = position;
            app.CarParametersPanel.Position = position;
            app.CarParametersPanel.Position(2) = position(2) - 41;
            app.ParametersPanel.Position(1) = position(3) - app.ParametersPanel.Position(3);
            app.ParametersPanel.Position(2) = app.CarParametersPanel.Position(2);
            app.ParametersPanel.Position(4) = app.CarParametersPanel.Position(4) + 1;
        end

        % Close request function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureCloseRequest(app, event)
            close_system(app.Model, false);
            delete(app);
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushedFcn(app, event)
            
            changeAppState(app, AppState.CarParameters);
        end

        % Button pushed function: CancelButton
        function CarParametersCancelButtonPushedFcn(app, event)
            changeAppState(app, AppState.Start);
        end

        % Button pushed function: SaveButton
        function CarParametersSaveButtonPushedFcn(app, event)
            
            app.DictParams.carMass.Value = app.CarMassEditField.Value;
            app.DictParams.carMass.Unit = app.CarMassUnitsDropDown.Value;
            app.DictEntries.carMass.setValue(app.DictParams.carMass);
            
            app.DictParams.cgHeight.Value = app.CGHeightEditField.Value;
            app.DictParams.cgHeight.Unit = app.CGHeightUnitsDropDown.Value;
            app.DictEntries.cgHeight.setValue(app.DictParams.cgHeight);
            
            app.DictParams.cgLength.Value = app.CGLengthEditField.Value;
            app.DictParams.cgLength.Unit = app.CGLengthUnitsDropDown.Value;
            app.DictEntries.cgLength.setValue(app.DictParams.cgLength);
            
            app.DictParams.wheelbase.Value = app.WheelbaseEditField.Value;
            app.DictParams.Wheelbase.Unit = app.WheelbaseUnitsDropDown.Value;
            app.DictEntries.wheelbase.setValue(app.DictParams.wheelbase);
            
            app.DictParams.yawInertia.Value = app.YawInertiaEditField.Value;
            app.DictParams.yawInertia.Unit = app.YawInertiaUnitsDropDown.Value;
            app.DictEntries.yawInertia.setValue(app.DictParams.yawInertia);
            
            app.DictParams.trackWidth.Value = [app.FrontTrackWidthEditField.Value app.RearTrackWidthEditField.Value];
            app.DictParams.trackWidth.Unit = app.TrackWidthUnitsDropDown.Value;
            app.DictEntries.trackWidth.setValue(app.DictParams.trackWidth);
            
            app.DictParams.springRates.Value = [app.FrontSpringRateEditField.Value app.RearSpringRateEditField.Value];
            app.DictParams.springRates.Unit = app.SpringRateUnitsDropDown.Value;
            app.DictEntries.springRates.setValue(app.DictParams.springRates);
            
            app.DictParams.damperRates.Value = [app.FrontDamperRateEditField.Value app.RearDamperRateEditField.Value];
            app.DictParams.damperRates.Unit = app.DamperRateUnitsDropDown.Value;
            app.DictEntries.damperRates.setValue(app.DictParams.damperRates);
            
            saveChanges(app.Dict);
            changeAppState(app, AppState.Start);
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeSelectionChangedFcn(app, event)
            selectedButton = app.SimulationTypeButtonGroup.SelectedObject;
            if selectedButton.Text == "Laptime"
                app.ManeuverLabel.Text = "Track";
            else
                app.ManeuverLabel.Text = "Maneuever";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFRVehicleModelUIFigure = uifigure('Visible', 'off');
            app.RFRVehicleModelUIFigure.Name = 'RFR Vehicle Model';
            app.RFRVehicleModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureCloseRequest, true);
            app.RFRVehicleModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureSizeChanged, true);
            app.MainMenuPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.SidebarPanel = uipanel(app.MainMenuPanel);
            app.SimulatePanel = uipanel(app.SidebarPanel);
            app.SimulatePanel.Title = 'Simulate';
            app.SimulateButton = uibutton(app.SimulatePanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SimulationTypeButtonGroup = uibuttongroup(app.SimulatePanel);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeSelectionChangedFcn, true);
            app.LaptimeButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.LaptimeButton.Enable = 'off';
            app.LaptimeButton.Text = 'Laptime';
            app.ManeuverButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.ManeuverButton.Text = 'Maneuver';
            app.ManeuverButton.Value = true;
            app.TrackOrManeuverSelectionPanel = uipanel(app.SimulatePanel);
            app.TrackOrManeuverSelectionPanel.Title = 'Maneuever';
            app.TrackorManeueverDropDown = uidropdown(app.TrackOrManeuverSelectionPanel);
            app.TrackorManeueverDropDown.Items = {};
            app.TrackorManeueverDropDown.Value = {};
            app.EditButton = uibutton(app.TrackOrManeuverSelectionPanel, 'push');
            app.EditButton.Text = 'Edit';
            app.CreateButton_2 = uibutton(app.TrackOrManeuverSelectionPanel, 'push');
            app.CreateButton_2.Enable = 'off';
            app.CreateButton_2.Text = 'Create';
            app.BrowseButton_2 = uibutton(app.TrackOrManeuverSelectionPanel, 'push');
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.VehicleSelectionPanel = uipanel(app.SimulatePanel);
            app.VehicleSelectionPanel.Title = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.VehicleSelectionPanel);
            app.VehicleDropDown.Items = {};
            app.VehicleDropDown.Value = {};
            app.ParametersButton = uibutton(app.VehicleSelectionPanel, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushedFcn, true);
            app.ParametersButton.Text = 'Parameters';
            app.CreateButton = uibutton(app.VehicleSelectionPanel, 'push');
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.BrowseButton = uibutton(app.VehicleSelectionPanel, 'push');
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.AnalysisPanel = uipanel(app.SidebarPanel);
            app.AnalysisPanel.Title = 'Analysis';
            app.TitlePanel = uipanel(app.MainMenuPanel);
            app.Image = uiimage(app.TitlePanel);
            app.Image.ImageSource = 'RFRlogo.png';
            app.RyersonFormulaRacingLabel = uilabel(app.TitlePanel);
            app.RyersonFormulaRacingLabel.Text = 'Ryerson Formula Racing';
            app.VehicleModelLabel = uilabel(app.TitlePanel);
            app.VehicleModelLabel.Text = 'Vehicle Model';
            app.SimulationPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.RightSidePanel = uipanel(app.SimulationPanel);
            app.ManageSimulationPanel = uipanel(app.RightSidePanel);
            app.StopButton = uibutton(app.ManageSimulationPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.ManageSimulationPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RHSLeftPanel = uipanel(app.RightSidePanel);
            app.GearLabel = uilabel(app.RHSLeftPanel);
            app.GearLabel.Text = '1';
            app.GearTextLabel = uilabel(app.RHSLeftPanel);
            app.GearTextLabel.Text = 'Gear';
            app.ThrottleBrakePanel = uipanel(app.RHSLeftPanel);
            app.BrakeGaugeLabel = uilabel(app.ThrottleBrakePanel);
            app.BrakeGaugeLabel.Text = 'Brake';
            app.BrakeGauge = uigauge(app.ThrottleBrakePanel, 'linear');
            app.ThrottleGauge_2Label = uilabel(app.ThrottleBrakePanel);
            app.ThrottleGauge_2Label.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.ThrottleBrakePanel, 'linear');
            app.RHSRPanel = uipanel(app.RightSidePanel);
            app.SpeedGaugeLabel = uilabel(app.RHSRPanel);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.RHSRPanel, 'circular');
            app.RPMGaugeLabel = uilabel(app.RHSRPanel);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.RHSRPanel, 'circular');
            app.RPMGauge.Limits = [4 11];
            app.RPMGauge.Value = 4;
            app.x1000Label = uilabel(app.RHSRPanel);
            app.x1000Label.Text = 'x1000';
            app.kmhLabel = uilabel(app.RHSRPanel);
            app.kmhLabel.Text = 'km/h';
            app.TimeLabel = uilabel(app.SimulationPanel);
            app.TimeLabel.Text = '0:00:00';
            app.CarParametersPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.ParametersPanel = uipanel(app.CarParametersPanel);
            app.SuspensionPanel = uipanel(app.ParametersPanel);
            app.SuspensionPanel.Title = 'Suspension';
            app.ChangeSuspensionGeometryButton = uibutton(app.SuspensionPanel, 'push');
            app.ChangeSuspensionGeometryButton.Text = 'Change suspension geometry';
            app.SpringRatesPanel = uipanel(app.SuspensionPanel);
            app.SpringRatesPanel.Title = 'Spring Rates';
            app.FrontLabel = uilabel(app.SpringRatesPanel);
            app.FrontLabel.Text = 'Front:';
            app.FrontSpringRateEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.RearLabel = uilabel(app.SpringRatesPanel);
            app.RearLabel.Text = 'Rear:';
            app.RearSpringRateEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.SpringRateUnitsDropDown = uidropdown(app.SpringRatesPanel);
            app.SpringRateUnitsDropDown.Items = {'N/m', 'N/mm', '', 'lbf/in'};
            app.SpringRateUnitsDropDown.Value = 'N/m';
            app.DamperRatesPanel = uipanel(app.SuspensionPanel);
            app.DamperRatesPanel.Title = 'Damper Rates';
            app.FrontLabel_2 = uilabel(app.DamperRatesPanel);
            app.FrontLabel_2.Text = 'Front:';
            app.FrontDamperRateEditField = uieditfield(app.DamperRatesPanel, 'numeric');
            app.RearLabel_2 = uilabel(app.DamperRatesPanel);
            app.RearLabel_2.Text = 'Rear:';
            app.RearDamperRateEditField = uieditfield(app.DamperRatesPanel, 'numeric');
            app.DamperRateUnitsDropDown = uidropdown(app.DamperRatesPanel);
            app.DamperRateUnitsDropDown.Items = {'N*s/m', 'N*s/mm', 'lbf*s/in'};
            app.DamperRateUnitsDropDown.Value = 'N*s/m';
            app.SteeringPanel = uipanel(app.ParametersPanel);
            app.SteeringPanel.Title = 'Steering';
            app.ChangeSteeringGeometryButton = uibutton(app.SteeringPanel, 'push');
            app.ChangeSteeringGeometryButton.Text = 'Change steering geometry';
            app.ToeAnglesPanel = uipanel(app.SteeringPanel);
            app.ToeAnglesPanel.Title = 'Toe Angles';
            app.FrontLabel_3 = uilabel(app.ToeAnglesPanel);
            app.FrontLabel_3.Text = 'Front:';
            app.FrontToeEditField = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.RearLabel_3 = uilabel(app.ToeAnglesPanel);
            app.RearLabel_3.Text = 'Rear:';
            app.RearToeEditField = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.FrontToeUnitsDrownDown = uidropdown(app.ToeAnglesPanel);
            app.FrontToeUnitsDrownDown.Items = {'deg', 'rad'};
            app.FrontToeUnitsDrownDown.Value = 'deg';
            app.RearToeUnitsDropDown = uidropdown(app.ToeAnglesPanel);
            app.RearToeUnitsDropDown.Items = {'deg', 'rad'};
            app.RearToeUnitsDropDown.Value = 'deg';
            app.CasterUnitsDropDown = uidropdown(app.SteeringPanel);
            app.CasterUnitsDropDown.Items = {'deg', 'rad'};
            app.CasterUnitsDropDown.Value = 'deg';
            app.CasteranglesLabel = uilabel(app.SteeringPanel);
            app.CasteranglesLabel.Text = 'Caster angles:';
            app.CasterEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.GeneralPanel = uipanel(app.ParametersPanel);
            app.GeneralPanel.Title = 'General';
            app.CarMassUnitsDropDown = uidropdown(app.GeneralPanel);
            app.CarMassUnitsDropDown.Items = {'kg', 'lbm'};
            app.CarMassUnitsDropDown.Value = 'kg';
            app.TiresLabel = uilabel(app.GeneralPanel);
            app.TiresLabel.Text = 'Tires:';
            app.TiresDropDown = uidropdown(app.GeneralPanel);
            app.CGPositionPanel = uipanel(app.GeneralPanel);
            app.CGPositionPanel.Title = 'CG Position';
            app.HeightLabel = uilabel(app.CGPositionPanel);
            app.HeightLabel.Text = 'Height:';
            app.CGHeightEditField = uieditfield(app.CGPositionPanel, 'numeric');
            app.CGHeightEditField.Tooltip = {'Vertical distance from ground to CG, when car is loaded and stationary'};
            app.CGHeightUnitsDropDown = uidropdown(app.CGPositionPanel);
            app.CGHeightUnitsDropDown.Items = {'m', 'mm', 'in'};
            app.CGHeightUnitsDropDown.Value = 'mm';
            app.CGLengthUnitsDropDown = uidropdown(app.CGPositionPanel);
            app.CGLengthUnitsDropDown.Items = {'m', 'mm', 'in'};
            app.CGLengthUnitsDropDown.Value = 'mm';
            app.LengthEditFieldLabel = uilabel(app.CGPositionPanel);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.CGLengthEditField = uieditfield(app.CGPositionPanel, 'numeric');
            app.WheelbaseLabel = uilabel(app.GeneralPanel);
            app.WheelbaseLabel.Text = 'Wheelbase:';
            app.WheelbaseEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.WheelbaseUnitsDropDown = uidropdown(app.GeneralPanel);
            app.WheelbaseUnitsDropDown.Items = {'m', 'mm', 'ft', 'in'};
            app.WheelbaseUnitsDropDown.Value = 'm';
            app.YawinertiaLabel = uilabel(app.GeneralPanel);
            app.YawinertiaLabel.Text = 'Yaw inertia:';
            app.YawInertiaEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.YawInertiaUnitsDropDown = uidropdown(app.GeneralPanel);
            app.YawInertiaUnitsDropDown.Items = {'kg*m^2', 'lbm*inft^2'};
            app.YawInertiaUnitsDropDown.Value = 'kg*m^2';
            app.TrackWidthPanel = uipanel(app.GeneralPanel);
            app.TrackWidthPanel.Title = 'Track Width';
            app.FrontEditField_5Label = uilabel(app.TrackWidthPanel);
            app.FrontEditField_5Label.Text = 'Front:';
            app.FrontTrackWidthEditField = uieditfield(app.TrackWidthPanel, 'numeric');
            app.RearEditField_5Label = uilabel(app.TrackWidthPanel);
            app.RearEditField_5Label.Text = 'Rear:';
            app.RearTrackWidthEditField = uieditfield(app.TrackWidthPanel, 'numeric');
            app.TrackWidthUnitsDropDown = uidropdown(app.TrackWidthPanel);
            app.TrackWidthUnitsDropDown.Items = {'m', 'mm', 'ft', 'in'};
            app.TrackWidthUnitsDropDown.Value = 'm';
            app.CarmassEditFieldLabel = uilabel(app.GeneralPanel);
            app.CarmassEditFieldLabel.Text = 'Car mass:';
            app.CarMassEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.AerodynamicsPanel = uipanel(app.ParametersPanel);
            app.AerodynamicsPanel.Title = 'Aerodynamics';
            app.AeromassLabel = uilabel(app.AerodynamicsPanel);
            app.AeromassLabel.Text = 'Aero mass:';
            app.AeroMassEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.AeroMassUnitsDropDown = uidropdown(app.AerodynamicsPanel);
            app.AeroMassUnitsDropDown.Items = {'kg', 'lbm'};
            app.AeroMassUnitsDropDown.Value = 'kg';
            app.DragcoefficientEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.DragcoefficientEditFieldLabel.Text = 'Drag coefficient:';
            app.DragCoefficientEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.LiftcoefficientEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.LiftcoefficientEditFieldLabel.Text = 'Lift coefficient:';
            app.LiftCoefficientEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.FrontalareaLabel = uilabel(app.AerodynamicsPanel);
            app.FrontalareaLabel.Text = 'Frontal area:';
            app.FrontalAreaEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.FrontalAreaUnitsDropDown = uidropdown(app.AerodynamicsPanel);
            app.FrontalAreaUnitsDropDown.Items = {'m^2', 'ft^2', 'in^2'};
            app.FrontalAreaUnitsDropDown.Value = 'm^2';
            app.SaveButton = uibutton(app.ParametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @CarParametersSaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ParametersPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CarParametersCancelButtonPushedFcn, true);
            app.CancelButton.Text = 'Cancel';
            app.PowertrainPanel = uipanel(app.ParametersPanel);
            app.PowertrainPanel.Title = 'Powertrain';
            app.PowertrainTypeButtonGroup = uibuttongroup(app.PowertrainPanel);
            app.InternalcombustionButton = uiradiobutton(app.PowertrainTypeButtonGroup);
            app.InternalcombustionButton.Text = 'Internal combustion';
            app.InternalcombustionButton.Value = true;
            app.ElectricButton = uiradiobutton(app.PowertrainTypeButtonGroup);
            app.ElectricButton.Enable = 'off';
            app.ElectricButton.Text = 'Electric';
            % Show the figure after all components are created
            app.RFRVehicleModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehicle_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RFRVehicleModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFRVehicleModelUIFigure)
        end
    end
end